classdef PowermapApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        TimePanel                     matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        TimeSlider                    matlab.ui.control.Slider
        TimeLabel                     matlab.ui.control.Label
        FrequencybandPanel            matlab.ui.container.Panel
        FreqSlider                    matlab.ui.control.Slider
        FreqLabel                     matlab.ui.control.Label
        AverageoverfrequencyCheckBox  matlab.ui.control.CheckBox
        AverageovertimeCheckBox       matlab.ui.control.CheckBox
        LoadfileButton                matlab.ui.control.Button
        fileName                      matlab.ui.control.Label
        ProcessingparametersPanel     matlab.ui.container.Panel
        StartEditFieldLabel           matlab.ui.control.Label
        StartEditField                matlab.ui.control.NumericEditField
        StopEditFieldLabel            matlab.ui.control.Label
        StopEditField                 matlab.ui.control.NumericEditField
        AllfileCheckBox               matlab.ui.control.CheckBox
        FmaxEditFieldLabel            matlab.ui.control.Label
        FmaxEditField                 matlab.ui.control.NumericEditField
        ProcessMapButton              matlab.ui.control.Button
        WindowLengthEditFieldLabel    matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        TFtransformDropDownLabel      matlab.ui.control.Label
        TFtransformDropDown           matlab.ui.control.DropDown
        DisplaySpectrogramButton      matlab.ui.control.Button
        MapDropDownLabel              matlab.ui.control.Label
        MapDropDown                   matlab.ui.control.DropDown
        BackgroundDropDownLabel       matlab.ui.control.Label
        BackgroundDropDown            matlab.ui.control.DropDown
        NormDropDownLabel             matlab.ui.control.Label
        NormDropDown                  matlab.ui.control.DropDown
        SaveMapButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        filepath
        filefolder = './';
        maps
        map
        mapNorm
        timeIdx
        time
        freqIdx
        freq
        start
        stop
        background
        dBFunc = @(map, dB0) 10*log10(abs(map)./dB0);
    end
    
    
    methods (Access = private)
        
        function [azi, elev, mapInterp] = interpMap(app, mapTemp)
            
            if app.AverageoverfrequencyCheckBox.Value && app.AverageovertimeCheckBox.Value
                mapTemp = squeeze(mean(mean(mapTemp,1),2));
            elseif app.AverageoverfrequencyCheckBox.Value
                mapTemp = mean(mapTemp,1);
                mapTemp = squeeze(mapTemp(:,app.timeIdx,:));
            elseif app.AverageovertimeCheckBox.Value
                mapTemp = mean(mapTemp,2);
                mapTemp = squeeze(mapTemp(app.freqIdx,:,:));
            else
                mapTemp = squeeze(mapTemp(app.freqIdx,app.timeIdx,:));
            end
            
            mapTemp= app.selectNorm(app.NormDropDown.Value, mapTemp);
            
            interpStep = 0.05;
            azi = -pi:interpStep:pi; elev = -pi/2:interpStep:pi/2;
            [aziInterp, elevInterp] = meshgrid(azi, elev);
            Finterp = scatteredInterpolant(app.maps.aziSampling, app.maps.elevSampling, double(mapTemp), 'natural', 'linear');
            mapInterp = fliplr(Finterp(aziInterp, elevInterp) + eps);
            clear mapTemp
        end
        
        function app = initUI(app)
            app.timeIdx = 1;
            app.time = round(app.maps.tMap(app.timeIdx),2);
            app.freqIdx = 1;
            app.freq = round(app.maps.f(app.freqIdx));
            app.TimeSlider.Limits = [1 size(app.maps.cropacMap,2)];
            app.TimeSlider.Value = app.timeIdx;
            app.TimeSlider.Visible = 1;
            app.TimeLabel.Text = [char(string(app.time)) ' s'];
            app.FreqSlider.Limits = [1 size(app.maps.cropacMap,1)];
            app.FreqSlider.Value = app.freqIdx;
            app.FreqSlider.Visible = 1;
            app.FreqLabel.Text = [char(string(app.freq)), ' Hz'];
            app.AverageoverfrequencyCheckBox.Value = 0;
            app.AverageovertimeCheckBox.Value = 0;
            app.map = app.MapDropDown.Value;
            app.selectMap(app.map);
            app.selectBackground(app.BackgroundDropDown.Value);
        end
        
        function [] = displayMap(app, value)
            mapTemp = app.selectMap(value);
            [azi, elev, mapInterp] = app.interpMap(mapTemp);
            imagesc(app.UIAxes, azi*180/pi, elev*180/pi, app.background, 'AlphaData', 0.5); hold(app.UIAxes, "on");
            imagesc(app.UIAxes, azi*180/pi, elev*180/pi, mapInterp, 'AlphaData', 1 - 0.5); hold(app.UIAxes, "off");
            caxis(app.UIAxes,[max(max(mapInterp)) - 10, max(max(mapInterp)) ]);
            c = colorbar(app.UIAxes); c.Label.String =  'Power (dB)';
            app.UIAxes.YDir = 'normal';
        end
        
        function map = selectMap(app, value)
            if ~isempty(app.maps)
                switch value
                    case 'PWD'
                        map= app.maps.pwdMap;
                    case 'MVDR'
                        map = app.maps.mvdrMap;
                    case 'CroPaC LCMV'
                        map = app.maps.cropacMap;
                    otherwise
                        map = app.maps.pwdMap;
                end
            end
        end
        
        function [] = selectBackground(app, value)
            switch value
                case '2008 26/05'
                    app.background = flipud(imread("img2008.png"));
                case 'eDS3 26/05'
                    app.background = flipud(imread("imgDS3.png"));
                case 'eDS3 27/05'
                    app.background = flipud(imread("imgDS3_27_05.png"));
                case 'Browse..'
                    [imgName, imgFolder] =  uigetfile({'*.*',  'All Files (*.*)'}, 'Select an Image');
                    app.background = flipud(imread([imgFolder, imgName]));
                otherwise
                    %app.background = zeros(imfinfo("img2008.png").Height, imfinfo("img2008.png").Width);
                    app.background = zeros(10, 20);
                    
            end
        end
        
        function mapNorm = selectNorm(app, value, oldMap)
            if ~isempty(app.maps)
                switch value
                    case 'dB Relative'
                        mapNorm = app.dBFunc(oldMap, max(max(max(abs(oldMap)))));
                    case 'dB Abs'
                        mapNorm = app.dBFunc(oldMap, 1);
                    case 'Linear Relative'
                        mapNorm = oldMap./max(max(abs(oldMap)));
                    otherwise
                        mapNorm = oldMap;
                end
            end
        end
        
        function [] = displaySpec(app)
            imagesc(app.UIAxes_2, app.maps.tMap, app.maps.f, db(app.maps.S(:,:,1)));
            hold(app.UIAxes_2, 'on');
            if ~app.AverageovertimeCheckBox.Value && ~isempty(app.maps)
                xline(app.UIAxes_2, app.maps.tMap(app.timeIdx), ':');
            end
            if ~app.AverageoverfrequencyCheckBox.Value
                yline(app.UIAxes_2, app.maps.f(app.freqIdx), ':');
            end
            hold(app.UIAxes_2, 'off');
            xlim(app.UIAxes_2, [app.maps.tMap(1), app.maps.tMap(end)]);
            ylim(app.UIAxes_2, [app.maps.f(1), app.maps.f(end)]);
            c = colorbar(app.UIAxes_2); c.Label.String =  'Power (dB)';
            caxis(app.UIAxes_2 ,[max(max(db(app.maps.S(:,:,1)))) - 100, ...
                max(max(db(app.maps.S(:,:,1))))])
            app.UIAxes_2.YDir = 'normal';
        end
        
        function [] = loadPreComputedMap(app)
            app.maps = load(app.filepath).maps;
            app = app.initUI();
            app.displaySpec();
            app.displayMap(app.map);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            app.ProcessingparametersPanel.Visible = false;
            [file,app.filefolder] = uigetfile([app.filefolder, '*.*'], 'Select an Audio File or a Map File');%);%{[app.filefolder, '*.*'],  'All Files (*.*)'}, 'Select an Audio File or a Map File'); %'D:\26_05_21\processed\Rotated8000Hz\[audio]3PA.wav'; %
            if isequal(file,0) || isequal(path,0)
                return
            end
            app.filepath = [app.filefolder, file];
            [~, name, ext] = fileparts(app.filepath);
            app.fileName.Text = [name, ext];
            if ext == ".wav"
                app.ProcessingparametersPanel.Visible = true;
                app.StartEditField.Value = 0;
                app.StopEditField.Value = floor(audioinfo(app.filepath).Duration*10)/10;
            else
                app.loadPreComputedMap();
            end
            
        end

        % Button pushed function: ProcessMapButton
        function ProcessMapButtonPushed(app, event)
            app.start = app.StartEditField.Value;
            app.stop = app.StopEditField.Value;
            app.maps.transform = app.TFtransformDropDown.Value;
            app.maps = powermaps('filepath', app.filepath, 'SHOrder', 3, ...
                'tDesignOrder', 15, 'winLen', app.WindowLengthEditField.Value, 'fmax', app.FmaxEditField.Value, ...
                'start', app.start, 'stop', app.stop, 'transform', app.maps.transform);
            app.maps = app.maps.processMap();
            app = app.initUI();
            app.displaySpec();
            app.displayMap(app.map);
        end

        % Value changed function: AverageovertimeCheckBox
        function AverageovertimeCheckBoxValueChanged(app, event)
            if ~isempty(app.maps)
                if app.AverageovertimeCheckBox.Value == 1
                    app.TimeSlider.Visible = false;
                else
                    app.TimeSlider.Visible = true;
                end
                if any(app.maps.pwdMap, 'all')
                    app.displaySpec();
                    app.displayMap(app.map);
                end
            end
        end

        % Value changed function: AverageoverfrequencyCheckBox
        function AverageoverfrequencyCheckBoxValueChanged(app, event)
            if ~isempty(app.maps)
                if app.AverageoverfrequencyCheckBox.Value == 1
                    app.FreqSlider.Visible = false;
                else
                    app.FreqSlider.Visible = true;
                end
                if any(app.maps.pwdMap, 'all')
                    app.displaySpec();
                    app.displayMap(app.map);
                end
            end
        end

        % Value changed function: FreqSlider
        function FreqSliderValueChanged(app, event)
            if ~isempty(app.maps)
                value = app.FreqSlider.Value;
                app.freqIdx = floor(value);
                app.freq = round(app.maps.f(app.freqIdx));
                app.FreqLabel.Text = [char(string(app.freq)), ' Hz'];
                if any(app.maps.pwdMap, 'all')
                    app.displaySpec();
                    app.displayMap(app.map);
                end
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            if ~isempty(app.maps)
                value = app.TimeSlider.Value;
                app.timeIdx = floor(value);
                app.time = round(app.maps.tMap(app.timeIdx),2);
                app.TimeLabel.Text = [char(string(app.time)), ' s'];
                if any(app.maps.pwdMap, 'all')
                    app.displaySpec();
                    app.displayMap(app.map);
                end
            end
        end

        % Value changed function: AllfileCheckBox
        function AllfileCheckBoxValueChanged(app, event)
            value = app.AllfileCheckBox.Value;
            if value == 1
                app.StartEditField.Value = 0;
                app.StopEditField.Value = audioinfo(app.filepath).Duration;
            end
        end

        % Value changed function: StopEditField
        function StopEditFieldValueChanged(app, event)
            value = app.StopEditField.Value;
            app.stop = value;
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            app.start = value;
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            app.map = app.MapDropDown.Value;
            if ~isempty(app.maps)
                app.displayMap(app.map);
            end
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            value = app.BackgroundDropDown.Value;
            app.selectBackground(value);
            if ~isempty(app.maps)
                app.displayMap(app.map);
            end
        end

        % Value changed function: NormDropDown
        function NormDropDownValueChanged(app, event)
            if ~isempty(app.maps)
                app.displayMap(app.map);
            end
        end

        % Callback function
        function TFtransformDropDownValueChanged(app, event)
            value = app.TFtransformDropDown.Value;
            app.maps.transform = value;
        end

        % Button pushed function: SaveMapButton
        function SaveMapButtonPushed(app, event)
            if ~isempty(app.maps)
                maps = app.maps;
                uisave('maps', app.filefolder);
                clear maps
            end
        end

        % Button pushed function: DisplaySpectrogramButton
        function DisplaySpectrogramButtonPushed(app, event)
            app.start = app.StartEditField.Value;
            app.stop = app.StopEditField.Value;
            app.maps.transform = app.TFtransformDropDown.Value;
            app.maps = powermaps('filepath', app.filepath, 'SHOrder', 3, ...
                'tDesignOrder', 15, 'winLen', app.WindowLengthEditField.Value, 'fmax', app.FmaxEditField.Value, ...
                'start', app.start, 'stop', app.stop, 'transform', app.maps.transform);
            app = app.initUI();
            app.displaySpec();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Powermap')
            xlabel(app.UIAxes, 'Azimuth (°)')
            ylabel(app.UIAxes, 'Elevation (°)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-90 90];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Spectrogram of s_{00}')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Frequency (Hz)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time :';
            app.Label = uilabel(app.TimePanel);
            app.Label.Text = {''; ''};
            app.TimeSlider = uislider(app.TimePanel);
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MajorTickLabels = {''};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.MinorTicks = [];
            app.TimeLabel = uilabel(app.TimePanel);
            app.TimeLabel.Text = '';
            app.FrequencybandPanel = uipanel(app.UIFigure);
            app.FrequencybandPanel.Title = 'Frequency band : ';
            app.FreqSlider = uislider(app.FrequencybandPanel);
            app.FreqSlider.MajorTicks = [];
            app.FreqSlider.ValueChangedFcn = createCallbackFcn(app, @FreqSliderValueChanged, true);
            app.FreqSlider.MinorTicks = [];
            app.FreqLabel = uilabel(app.FrequencybandPanel);
            app.FreqLabel.Text = '';
            app.AverageoverfrequencyCheckBox = uicheckbox(app.UIFigure);
            app.AverageoverfrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageoverfrequencyCheckBoxValueChanged, true);
            app.AverageoverfrequencyCheckBox.Text = 'Average over frequency';
            app.AverageovertimeCheckBox = uicheckbox(app.UIFigure);
            app.AverageovertimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageovertimeCheckBoxValueChanged, true);
            app.AverageovertimeCheckBox.Text = 'Average over time';
            app.LoadfileButton = uibutton(app.UIFigure, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.fileName = uilabel(app.UIFigure);
            app.fileName.Text = '';
            app.ProcessingparametersPanel = uipanel(app.UIFigure);
            app.ProcessingparametersPanel.Title = 'Processing parameters';
            app.StartEditFieldLabel = uilabel(app.ProcessingparametersPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StopEditFieldLabel = uilabel(app.ProcessingparametersPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @StopEditFieldValueChanged, true);
            app.StopEditField.Value = 1;
            app.AllfileCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.AllfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllfileCheckBoxValueChanged, true);
            app.AllfileCheckBox.Text = 'All file';
            app.FmaxEditFieldLabel = uilabel(app.ProcessingparametersPanel);
            app.FmaxEditFieldLabel.Text = 'Fmax';
            app.FmaxEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.FmaxEditField.Value = 1000;
            app.ProcessMapButton = uibutton(app.ProcessingparametersPanel, 'push');
            app.ProcessMapButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessMapButtonPushed, true);
            app.ProcessMapButton.Text = 'Process Map';
            app.WindowLengthEditFieldLabel = uilabel(app.ProcessingparametersPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.WindowLengthEditField.Value = 1024;
            app.TFtransformDropDownLabel = uilabel(app.ProcessingparametersPanel);
            app.TFtransformDropDownLabel.Text = 'TF transform';
            app.TFtransformDropDown = uidropdown(app.ProcessingparametersPanel);
            app.TFtransformDropDown.Items = {'STFT', 'afSTFT'};
            app.TFtransformDropDown.Value = 'STFT';
            app.DisplaySpectrogramButton = uibutton(app.ProcessingparametersPanel, 'push');
            app.DisplaySpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySpectrogramButtonPushed, true);
            app.DisplaySpectrogramButton.Text = 'Display Spectrogram';
            app.MapDropDownLabel = uilabel(app.UIFigure);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.UIFigure);
            app.MapDropDown.Items = {'PWD', 'MVDR', 'CroPaC LCMV'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'PWD';
            app.BackgroundDropDownLabel = uilabel(app.UIFigure);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.UIFigure);
            app.BackgroundDropDown.Items = {'None', '2008 26/05', '2008 27/05', 'eDS3 26/05', 'eDS3 27/05', 'Browse..'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = 'None';
            app.NormDropDownLabel = uilabel(app.UIFigure);
            app.NormDropDownLabel.Text = 'Norm';
            app.NormDropDown = uidropdown(app.UIFigure);
            app.NormDropDown.Items = {'dB Relative', 'dB Abs'};
            app.NormDropDown.ValueChangedFcn = createCallbackFcn(app, @NormDropDownValueChanged, true);
            app.NormDropDown.Value = 'dB Relative';
            app.SaveMapButton = uibutton(app.UIFigure, 'push');
            app.SaveMapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMapButtonPushed, true);
            app.SaveMapButton.Text = 'Save Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowermapApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end